classdef InterfazApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DescargardatosMPU6050Button  matlab.ui.control.Button
        KPEditFieldLabel             matlab.ui.control.Label
        KPEditField                  matlab.ui.control.EditField
        KIEditFieldLabel             matlab.ui.control.Label
        KIEditField                  matlab.ui.control.EditField
        KDEditFieldLabel             matlab.ui.control.Label
        KDEditField                  matlab.ui.control.EditField
        EnviarparmetrosButton        matlab.ui.control.Button
        AjustarparmetrosLabel        matlab.ui.control.Label
        DescargarDatosdenguloLabel   matlab.ui.control.Label
        BallandBeamSystemLabel       matlab.ui.control.Label
        xGaugeLabel                  matlab.ui.control.Label
        xGauge                       matlab.ui.control.LinearGauge
        yGaugeLabel                  matlab.ui.control.Label
        yGauge                       matlab.ui.control.LinearGauge
        zGaugeLabel                  matlab.ui.control.Label
        zGauge                       matlab.ui.control.LinearGauge
        x_gGaugeLabel                matlab.ui.control.Label
        x_gGauge                     matlab.ui.control.LinearGauge
        y_gGaugeLabel                matlab.ui.control.Label
        y_gGauge                     matlab.ui.control.LinearGauge
        z_gGaugeLabel                matlab.ui.control.Label
        z_gGauge                     matlab.ui.control.LinearGauge
        DatosdelaMPULabel            matlab.ui.control.Label
        ngulodereferenciaLabel       matlab.ui.control.Label
        ngulodereferenciaEditField   matlab.ui.control.NumericEditField
        AnguloGaugeLabel             matlab.ui.control.Label
        AnguloGauge                  matlab.ui.control.LinearGauge
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global x
            global y
            global z
            global x_a 
            global y_a
            global z_a
            global angx
            N = 1000;
            t = linspace(0,40,N)';
            K = 10;
            
            if exist ("s", "var")==0
                s = serialport('COM6',115200);
            end
            h1 = plot(app.UIAxes2,t,zeros(N,1));
            xlim(app.UIAxes2,[0,t(end)]);
            ylim(app.UIAxes2,[-5,5]);
            buffer = zeros(K,1);
            k = 1;
            w=1;  
                
            while(1)
                 data = readline(s);
                 data_split = split(data,"|");
                 x = data_split(1);
                 y = data_split(2);
                 z = data_split(3);
                 x_a = data_split(4);
                 y_a = data_split(5);
                 z_a = data_split(6);
                 %angx=data_split(7);

                 p(w,1) = str2double(z_a);
                 drawnow limitrate
                         buffer(k) = p(w);
 
                 if(k == K)
                     h1.YData((w-K+1):w) = buffer;   
                     drawnow limitrate
                     k = 1;
                 else
                     k = k + 1;    
                 end
                w=w+1
                x1=str2double(x)
                y1=str2double(y)
                z1=str2double(z)
                xa1=str2double(x_a)
                ya1=str2double(y_a)
                za1=str2double(z_a)
                ang= (atan2(x1, sqrt (y1 * y1 + z1 * z1))*180.0)/3.14
                %angle=str2double(ang)
                app.xGauge.Value=x1;
                app.yGauge.Value=y1;
                app.zGauge.Value=z1;
                app.x_gGauge.Value=xa1;
                app.y_gGauge.Value=ya1;
                app.y_gGauge.Value=za1;
                %app.AnguloGauge.Value=angle;

            end


        end

        % Button down function: UIAxes, UIAxes2
        function UIAxesButtonDown(app, event)
            global x
            global y
            global z
            global x_a 
            global y_a
            global z_a
            
%             x1=str2double(x)
%             y1=str2double(y)
%             z1=str2double(z)
%             xa1=str2double(x_a)
%             ya1=str2double(y_a)
%             za1=str2double(z_a)

            
            angle=-3*str2double(z_a)
            
               l=12.5;
               a=plot(app.UIAxes,[-l/2 l/2], [0 0], 'LineWidth',5);
               a.Color='#EDB120';
               hold (app.UIAxes,"on")
               b=plot(app.UIAxes, [-l/2 0], [0,l], 'LineWidth', 5);
               b.Color='#EDB120';
               hold(app.UIAxes, "on");
               c=plot(app.UIAxes, [l/2 0], [0, l], 'LineWidth', 5);
               c.Color= '#EDB120';
               hold(app.UIAxes, "on");
               lineLenght=15/2;
               %angle=z_a;
                   a1=0;
                   b1=l;
                   a2=a1+ lineLenght*cosd(angle);
                   b2=b1+ lineLenght*sind(angle);
                   a3=a1- lineLenght*cosd(angle);
                   b3=b1-lineLenght*sind(angle);
                   d=plot(app.UIAxes, [a2 a3], [b2 b3], "LineWidth", 2);
                   d.Color='black';
                   hold(app.UIAxes,"on");
                   xlim(app.UIAxes, [-15 15]);
                   ylim(app.UIAxes, [-10 20]);


%                 app.xGauge.Value=x1;
%                 %app.yGauge.Value=y1;
%                 app.zGauge.Value=z1;
%                 app.x_gGauge.Value=xa1;
%                 app.y_gGauge.Value=ya1;
%                 app.y_gGauge.Value=za1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DescargardatosMPU6050Button = uibutton(app.UIFigure, 'push');
            app.DescargardatosMPU6050Button.Text = 'Descargar datos MPU6050';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'text');
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'text');
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'text');
            app.EnviarparmetrosButton = uibutton(app.UIFigure, 'push');
            app.EnviarparmetrosButton.Text = 'Enviar parámetros';
            app.AjustarparmetrosLabel = uilabel(app.UIFigure);
            app.AjustarparmetrosLabel.Text = 'Ajustar parámetros';
            app.DescargarDatosdenguloLabel = uilabel(app.UIFigure);
            app.DescargarDatosdenguloLabel.Text = 'Descargar Datos de Ángulo';
            app.BallandBeamSystemLabel = uilabel(app.UIFigure);
            app.BallandBeamSystemLabel.Text = 'Ball and Beam System';
            app.xGaugeLabel = uilabel(app.UIFigure);
            app.xGaugeLabel.Text = 'x';
            app.xGauge = uigauge(app.UIFigure, 'linear');
            app.xGauge.Limits = [18 24];
            app.xGauge.MajorTicks = [18 20 22 24];
            app.yGaugeLabel = uilabel(app.UIFigure);
            app.yGaugeLabel.Text = 'y';
            app.yGauge = uigauge(app.UIFigure, 'linear');
            app.yGauge.Limits = [-5 5];
            app.yGauge.MajorTicks = [-5 -2.5 0 2.5 5];
            app.zGaugeLabel = uilabel(app.UIFigure);
            app.zGaugeLabel.Text = 'z';
            app.zGauge = uigauge(app.UIFigure, 'linear');
            app.zGauge.Limits = [-5 5];
            app.zGauge.MajorTicks = [-5 -2.5 0 2.5 5];
            app.x_gGaugeLabel = uilabel(app.UIFigure);
            app.x_gGaugeLabel.Text = 'x_g';
            app.x_gGauge = uigauge(app.UIFigure, 'linear');
            app.x_gGauge.Limits = [18 24];
            app.x_gGauge.MajorTicks = [18 20 22 24];
            app.y_gGaugeLabel = uilabel(app.UIFigure);
            app.y_gGaugeLabel.Text = 'y_g';
            app.y_gGauge = uigauge(app.UIFigure, 'linear');
            app.y_gGauge.Limits = [-5 5];
            app.y_gGauge.MajorTicks = [-5 -2.5 0 2.5 5];
            app.z_gGaugeLabel = uilabel(app.UIFigure);
            app.z_gGaugeLabel.Text = 'z_g';
            app.z_gGauge = uigauge(app.UIFigure, 'linear');
            app.z_gGauge.Limits = [-5 5];
            app.z_gGauge.MajorTicks = [-5 -2.5 0 2.5 5];
            app.DatosdelaMPULabel = uilabel(app.UIFigure);
            app.DatosdelaMPULabel.Text = 'Datos de la MPU';
            app.ngulodereferenciaLabel = uilabel(app.UIFigure);
            app.ngulodereferenciaLabel.Text = {'Ángulo de'; ' referencia'; ''};
            app.ngulodereferenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloGaugeLabel = uilabel(app.UIFigure);
            app.AnguloGaugeLabel.Text = 'Angulo';
            app.AnguloGauge = uigauge(app.UIFigure, 'linear');
            app.AnguloGauge.Limits = [50 100];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Beam')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ángulo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end